classdef signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        zoomSlider_2            matlab.ui.control.Slider
        zoomSlider_2Label       matlab.ui.control.Label
        resetButton_2           matlab.ui.control.Button
        resetButton             matlab.ui.control.Button
        zoomSlider              matlab.ui.control.Slider
        zoomSliderLabel         matlab.ui.control.Label
        sumButton               matlab.ui.control.Button
        ListBox                 matlab.ui.control.ListBox
        filenameEditField       matlab.ui.control.EditField
        filenameEditFieldLabel  matlab.ui.control.Label
        importButton            matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Items = {}; % Description
        current = {};
    end
    
    methods (Access = private)
        
        function plotAll(app, path)
%             figure(app.UIFigure);
            if length(path)<1
                return
            end
            Fs = 48000;
            if ischar(path)
                [data, ~] = audioread(path);
                app.current = data;
            else
                data = path;
            end
            t1 = app.zoomSlider.Value;
            t2 = app.zoomSlider_2.Value;
            time = (0:size(data,1)-1)/Fs*t1;
            plot(app.UIAxes, time, data);
            y = data;
            N = length(y);
            X_mags = abs(fft(y));
            bin_vals = [0 : N-1];
            fax_Hz = bin_vals*Fs/N*t2;
            N_2 = ceil(N/2);
            plot(app.UIAxes2,fax_Hz(1:N_2), X_mags(1:N_2));
        end
        
        
        function sumSignal(app, signals)
            if(length(signals)<=1)
                return
            end
            [data, Fs1] = audioread(signals{1});
            s = size(data, 1);
            Fs1 = 48000;
            for a = signals(1:end)         
                [x, F] = audioread(a{1});
                if s<size(x,1) 
                    s = size(data,1);
                else
                    s = size(x,1);
                end
            end
            for a = signals(2:end)
                [x, F] = audioread(a{1});
                data = data(1:s, 1) + x(1:s, 1);
            end
            app.current = data;
            time = (0:size(data,1)-1)/Fs1;
            plot(app.UIAxes, time, data);
            N = length(data);
            X_mags = abs(fft(data));
            bin_vals = [0 : N-1];
            fax_Hz = bin_vals*Fs1/N;
            N_2 = ceil(N/2);
            plot(app.UIAxes2,fax_Hz(1:N_2), X_mags(1:N_2));
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, a)
          app.ListBox.Items = app.Items;
        end

        % Button pushed function: importButton
        function importButtonPushed(app, event)
            [filename, path] = uigetfile('*.wav', '*.mp3'); %https://www.mathworks.com/matlabcentral/answers/466061-plot-a-sound-file-in-matlab-gui
            fullPath = strcat(path, filename);
            app.filenameEditField.Value=fullPath;
            app.zoomSlider.Value = 1;
            app.plotAll(fullPath);
            app.Items = [app.Items, fullPath];
            set(app.ListBox, 'Items', app.Items);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.zoomSlider.Value = 1;
            app.plotAll(value{1});
        end

        % Button pushed function: sumButton
        function sumButtonPushed(app, event)
            value = app.ListBox.Value;
            app.zoomSlider.Value = 1;
            app.sumSignal(value);
        end

        % Callback function
        function zoominButtonPushed(app, event)

        end

        % Value changing function: zoomSlider
        function zoomSliderValueChanging(app, event)
%             changingValue = event.Value;   
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes2.XLimMode = 'manual';
            app.plotAll(app.current);
        end

        % Value changed function: zoomSlider
        function zoomSliderValueChanged(app, event)
%             value = app.zoomSlider.Value;
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes2.XLimMode = 'manual';
            app.plotAll(app.current);
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.zoomSlider.Value = 1;
            reset(app.UIAxes.XAxis);
            reset(app.UIAxes.YAxis);
            app.plotAll(app.current);
        end

        % Button pushed function: resetButton_2
        function resetButton_2Pushed(app, event)
            app.zoomSlider_2.Value = 1;
            reset(app.UIAxes2.XAxis);
            reset(app.UIAxes2.YAxis);
            app.plotAll(app.current);
        end

        % Value changed function: zoomSlider_2
        function zoomSlider_2ValueChanged(app, event)
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes2.XLimMode = 'manual';
            app.plotAll(app.current);
        end

        % Value changing function: zoomSlider_2
        function zoomSlider_2ValueChanging(app, event)
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes2.XLimMode = 'manual';
            app.plotAll(app.current);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'Time(t)')
            ylabel(app.UIAxes, 'Data')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Frequency Domain')
            xlabel(app.UIAxes2, '(f)')
            ylabel(app.UIAxes2, '|PF(f)|')
            zlabel(app.UIAxes2, 'Z')
            app.importButton = uibutton(app.UIFigure, 'push');
            app.importButton.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.importButton.Text = 'import';
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'filename';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.sumButton = uibutton(app.UIFigure, 'push');
            app.sumButton.ButtonPushedFcn = createCallbackFcn(app, @sumButtonPushed, true);
            app.sumButton.Text = 'sum';
            app.zoomSliderLabel = uilabel(app.UIFigure);
            app.zoomSliderLabel.Text = 'zoom';
            app.zoomSlider = uislider(app.UIFigure);
            app.zoomSlider.Limits = [1 1000];
            app.zoomSlider.MajorTicks = [1 100 200 300 400 500 600 700 800 900 1000];
            app.zoomSlider.ValueChangedFcn = createCallbackFcn(app, @zoomSliderValueChanged, true);
            app.zoomSlider.ValueChangingFcn = createCallbackFcn(app, @zoomSliderValueChanging, true);
            app.zoomSlider.Value = 1;
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = {'reset'; ''};
            app.resetButton_2 = uibutton(app.UIFigure, 'push');
            app.resetButton_2.ButtonPushedFcn = createCallbackFcn(app, @resetButton_2Pushed, true);
            app.resetButton_2.Text = {'reset'; ''};
            app.zoomSlider_2Label = uilabel(app.UIFigure);
            app.zoomSlider_2Label.Text = 'zoom';
            app.zoomSlider_2 = uislider(app.UIFigure);
            app.zoomSlider_2.Limits = [1 50];
            app.zoomSlider_2.ValueChangedFcn = createCallbackFcn(app, @zoomSlider_2ValueChanged, true);
            app.zoomSlider_2.ValueChangingFcn = createCallbackFcn(app, @zoomSlider_2ValueChanging, true);
            app.zoomSlider_2.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end